classdef PV3eTelem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TelemetriePV3eUIFigure  matlab.ui.Figure
        EditField               matlab.ui.control.EditField
        EnvoyerButton           matlab.ui.control.Button
        Lamp                    matlab.ui.control.Lamp
        Label_2                 matlab.ui.control.Label
        ModeMoteurLabel         matlab.ui.control.Label
        PotentiometreKnob       matlab.ui.control.DiscreteKnob
        PotentiometreKnobLabel  matlab.ui.control.Label
        DistanceparcourueLabel  matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        RegenLamp               matlab.ui.control.Lamp
        RegenLampLabel          matlab.ui.control.Label
        DeadmanLamp             matlab.ui.control.Lamp
        DeadmanLampLabel        matlab.ui.control.Label
        DmarreurLamp            matlab.ui.control.Lamp
        DmarreurLampLabel       matlab.ui.control.Label
        FUELLamp                matlab.ui.control.Lamp
        FUELLampLabel           matlab.ui.control.Label
        VentilationLamp         matlab.ui.control.Lamp
        VentilationLampLabel    matlab.ui.control.Label
        ClignoDLamp             matlab.ui.control.Lamp
        ClignoDLampLabel        matlab.ui.control.Label
        EssuieLamp              matlab.ui.control.Lamp
        EssuieLampLabel         matlab.ui.control.Label
        LumierehabLamp          matlab.ui.control.Lamp
        LumierehabLampLabel     matlab.ui.control.Label
        FeuxDetresseLamp        matlab.ui.control.Lamp
        FeuxDetresseLampLabel   matlab.ui.control.Label
        ClignoGLamp             matlab.ui.control.Lamp
        ClignoGLampLabel        matlab.ui.control.Label
        PhareLamp               matlab.ui.control.Lamp
        PhareLampLabel          matlab.ui.control.Label
        VitesseGauge            matlab.ui.control.Gauge
        VitesseGaugeLabel       matlab.ui.control.Label
        TemperatureVESCLabel    matlab.ui.control.Label
        CoupleElecLabel         matlab.ui.control.Label
        TensionsuperCLabel      matlab.ui.control.Label
        RegimeElectriqueLabel   matlab.ui.control.Label
        StopButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        TensionLiPoLabel        matlab.ui.control.Label
        PressionCarbuLabel      matlab.ui.control.Label
        TempsInjectionLabel     matlab.ui.control.Label
        lambdaLabel             matlab.ui.control.Label
        TempeauLabel            matlab.ui.control.Label
        RegimeThermqueLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        serverOBJ % Description
        tcps
        run=0
    end
    
    methods (Access = public)
        
        
        function initServer(app)
            app.run = 1;
            %on cree une instance de TCPServer (contient les fonction utile
            %pour la comm TCP)
            app.serverOBJ = TCPServer;
            
            address = "46.105.28.70";
            
            %on lance le client TCP
            try 
                app.tcps = tcpclient(address,11000); 
            catch 
                return
            end
            writeline(app.tcps, "client");
            app.EnvoyerButton.Enable = true;
            
            app.Lamp.Color = getColor(app,1);
            while(app.run == 1)
                 %on regarde si on a reçu des données
                 if app.tcps.NumBytesAvailable > 0
                    %on stock les données reçu dans data
                    data = read(app.tcps,app.tcps.NumBytesAvailable,"uint8");
                    len = size(data);
                    len = len(2);
                    if len>=28
                        %on appel updateData qui decode les données brut en valeurs
                        r = app.serverOBJ.updateData(data, 25);
                        %on met a jour l'affichage
                        updateGUI(app, r);
                    end
                 end
                %obligatoire pour eviter de bloquer le programme
                drawnow();
            end
            writeline(app.tcps, "END");
            %on ferme la connexion
            clear app.tcps;
            app.Lamp.Color = getColor(app,0);
        end
        
        
        function c = getColor(~, boo)
            if boo == 0
                c = [0 0 0];
            else
                c = [0 1 0];
            end
        end
        
        
        function updateGUI(app, data)
            app.RegimeThermqueLabel.Text = "Régime thermique : "+data(1)+" tr/min";
            app.TempeauLabel.Text = "Temperature Eau : "+data(2) + " °C";
            app.lambdaLabel.Text = "Lambda : "+data(3) + "";
            app.PressionCarbuLabel.Text = "Pression carburant : "+ data(5) + " bar";
            app.TempsInjectionLabel.Text = "Temps injection : "+data(4) + " ms";
            app.TensionLiPoLabel.Text = "Tension LiPo : "+data(6) + " V";
            
            app.RegimeElectriqueLabel.Text = "Regime Moteur : " + data(7) + " tr/min";
            app.TensionsuperCLabel.Text = "Tension superC : " + data(8)+ " V";
            app.CoupleElecLabel.Text = "Couple elec: "+data(9) + " N.m";
            app.TemperatureVESCLabel.Text = "Temperature VESC : "+data(10)+ " °C";
            
            app.Label.Text = data(11) + "km/h";
            app.DistanceparcourueLabel.Text = "Distance parcourue : "+data(12)+" m";
            app.VitesseGauge.Value = data(11);
            
            app.PhareLamp.Color = getColor(app, data(13));
            app.EssuieLamp.Color = getColor(app, data(14));
            app.ClignoGLamp.Color = getColor(app, data(15));
            app.ClignoDLamp.Color = getColor(app, data(16));
            app.FeuxDetresseLamp.Color = getColor(app,data(17));
            app.VentilationLamp.Color = getColor(app, data(18));
            app.LumierehabLamp.Color = getColor(app, data(19));
            app.FUELLamp.Color = getColor(app,data(20));
            app.DmarreurLamp.Color= getColor(app,data(21));
            app.DeadmanLamp.Color = getColor(app,data(22));
            app.RegenLamp.Color= getColor(app,data(23));
            
            %on verifie que la valeur est valide
            potval = min(max(1,data(24)),size(app.PotentiometreKnob.Items,2));
            app.PotentiometreKnob.Value = app.PotentiometreKnob.Items(potval);
            
            app.ModeMoteurLabel.Text = "Mode Moteur : "+data(25);
            
        end
      
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.run==0
                initServer(app);
            end
        end

        % Close request function: TelemetriePV3eUIFigure
        function TelemetriePV3eUIFigureCloseRequest(app, event)
            app.run = 0;
            writeline(app.tcps, "END");
            delete(app);
        end

        % Button down function: TelemetriePV3eUIFigure
        function TelemetriePV3eUIFigureButtonDown(app, event)
 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.run = 0;
        end

        % Button pushed function: EnvoyerButton
        function EnvoyerButtonPushed(app, event)
            writeline(app.tcps, app.EditField.Value);
            app.EditField.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TelemetriePV3eUIFigure = uifigure('Visible', 'off');
            app.TelemetriePV3eUIFigure.Name = 'Telemetrie PV3e';
            app.TelemetriePV3eUIFigure.CloseRequestFcn = createCallbackFcn(app, @TelemetriePV3eUIFigureCloseRequest, true);
            app.TelemetriePV3eUIFigure.ButtonDownFcn = createCallbackFcn(app, @TelemetriePV3eUIFigureButtonDown, true);
            app.RegimeThermqueLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.RegimeThermqueLabel.Text = {'Regime Thermque'; ''};
            app.TempeauLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.TempeauLabel.Text = {'Temp eau'; ''};
            app.lambdaLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.lambdaLabel.Text = {'lambda'; ''};
            app.TempsInjectionLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.TempsInjectionLabel.Text = 'Temps Injection';
            app.PressionCarbuLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.PressionCarbuLabel.Text = 'Pression Carbu';
            app.TensionLiPoLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.TensionLiPoLabel.Text = 'Tension LiPo';
            app.StartButton = uibutton(app.TelemetriePV3eUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.TelemetriePV3eUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RegimeElectriqueLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.RegimeElectriqueLabel.Text = {'Regime Electrique'; ''};
            app.TensionsuperCLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.TensionsuperCLabel.Text = 'Tension superC';
            app.CoupleElecLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.CoupleElecLabel.Text = 'Couple Elec';
            app.TemperatureVESCLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.TemperatureVESCLabel.Text = 'Temperature VESC';
            app.VitesseGaugeLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.VitesseGaugeLabel.Text = 'Vitesse';
            app.VitesseGauge = uigauge(app.TelemetriePV3eUIFigure, 'circular');
            app.VitesseGauge.Limits = [0 80];
            app.PhareLampLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.PhareLampLabel.Text = 'Phare';
            app.PhareLamp = uilamp(app.TelemetriePV3eUIFigure);
            app.ClignoGLampLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.ClignoGLampLabel.Text = 'Cligno G';
            app.ClignoGLamp = uilamp(app.TelemetriePV3eUIFigure);
            app.FeuxDetresseLampLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.FeuxDetresseLampLabel.Text = 'Feux Detresse';
            app.FeuxDetresseLamp = uilamp(app.TelemetriePV3eUIFigure);
            app.LumierehabLampLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.LumierehabLampLabel.Text = 'Lumiere hab';
            app.LumierehabLamp = uilamp(app.TelemetriePV3eUIFigure);
            app.EssuieLampLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.EssuieLampLabel.Text = 'Essuie';
            app.EssuieLamp = uilamp(app.TelemetriePV3eUIFigure);
            app.ClignoDLampLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.ClignoDLampLabel.Text = 'Cligno D';
            app.ClignoDLamp = uilamp(app.TelemetriePV3eUIFigure);
            app.VentilationLampLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.VentilationLampLabel.Text = 'Ventilation';
            app.VentilationLamp = uilamp(app.TelemetriePV3eUIFigure);
            app.FUELLampLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.FUELLampLabel.Text = {'FUEL'; ''};
            app.FUELLamp = uilamp(app.TelemetriePV3eUIFigure);
            app.DmarreurLampLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.DmarreurLampLabel.Text = 'Démarreur';
            app.DmarreurLamp = uilamp(app.TelemetriePV3eUIFigure);
            app.DeadmanLampLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.DeadmanLampLabel.Text = 'Deadman';
            app.DeadmanLamp = uilamp(app.TelemetriePV3eUIFigure);
            app.RegenLampLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.RegenLampLabel.Text = 'Regen';
            app.RegenLamp = uilamp(app.TelemetriePV3eUIFigure);
            app.Label = uilabel(app.TelemetriePV3eUIFigure);
            app.DistanceparcourueLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.DistanceparcourueLabel.Text = 'Distance parcourue';
            app.PotentiometreKnobLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.PotentiometreKnobLabel.Enable = 'off';
            app.PotentiometreKnobLabel.Text = 'Potentiometre';
            app.PotentiometreKnob = uiknob(app.TelemetriePV3eUIFigure, 'discrete');
            app.PotentiometreKnob.Items = {'OFF', 'ID', 'E', 'HY', 'TH', 'C', 'R'};
            app.PotentiometreKnob.Enable = 'off';
            app.PotentiometreKnob.Value = 'OFF';
            app.ModeMoteurLabel = uilabel(app.TelemetriePV3eUIFigure);
            app.ModeMoteurLabel.Text = 'Mode Moteur';
            app.Label_2 = uilabel(app.TelemetriePV3eUIFigure);
            app.Label_2.Text = '';
            app.Lamp = uilamp(app.TelemetriePV3eUIFigure);
            app.EnvoyerButton = uibutton(app.TelemetriePV3eUIFigure, 'push');
            app.EnvoyerButton.ButtonPushedFcn = createCallbackFcn(app, @EnvoyerButtonPushed, true);
            app.EnvoyerButton.Enable = 'off';
            app.EnvoyerButton.Text = 'Envoyer';
            app.EditField = uieditfield(app.TelemetriePV3eUIFigure, 'text');
            % Show the figure after all components are created
            app.TelemetriePV3eUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PV3eTelem

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TelemetriePV3eUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TelemetriePV3eUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TelemetriePV3eUIFigure)
        end
    end
end